classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1          matlab.ui.Figure
        zEditField       matlab.ui.control.NumericEditField
        zEditFieldLabel  matlab.ui.control.Label
        yEditField       matlab.ui.control.NumericEditField
        yEditFieldLabel  matlab.ui.control.Label
        xEditField       matlab.ui.control.NumericEditField
        xEditFieldLabel  matlab.ui.control.Label
        q4Slider         matlab.ui.control.Slider
        q4SliderLabel    matlab.ui.control.Label
        q3Slider         matlab.ui.control.Slider
        q3SliderLabel    matlab.ui.control.Label
        q2Slider         matlab.ui.control.Slider
        q2SliderLabel    matlab.ui.control.Label
        q1Slider         matlab.ui.control.Slider
        q1SliderLabel    matlab.ui.control.Label
        ResumeButton     matlab.ui.control.Button
        eStopButton      matlab.ui.control.Button
        plot             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % place your class properties here
        deg = pi/180;
        robot;
        q = [0,pi/4,-pi/2,pi/4];
        pos = zeros(3,1);
    end

    methods (Access = private)
        function editRobotPos(app)
            x = app.xEditField.Value;
            y = app.yEditField.Value;
            z = app.zEditField.Value;
            tf = transl(x,y,z);
            app.q = app.robot.model.ikcon(tf,app.q);
            app.robot.model.animate(app.q);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function init(app, varargin)
            % Code to run once GUI is loaded successfully
            app.robot = DobotClass();
        end

        % Button pushed function: ResumeButton
        function resume(app, event)
            assignin("base","eStop",false);
        end

        % Button pushed function: eStopButton
        function eStop(app, event)
            assignin("base","eStop",true);
        end

        % Callback function
        function set_qz(app, event)
            qz = [0,0,0,0];
            app.robot.model.animate(qz);
            drawnow();
        end

        % Value changing function: q1Slider
        function sliding_q1(app, event)
            app.q(1) = event.Value*app.deg;
            app.robot.model.animate(app.q);
        end

        % Value changing function: q2Slider
        function sliding_q2(app, event)
            app.q(2) = event.Value*app.deg;
            app.robot.model.animate(app.q);            
        end

        % Value changing function: q3Slider
        function sliding_q3(app, event)
            app.q(3) = event.Value*app.deg;
            app.robot.model.animate(app.q);
        end

        % Value changing function: q4Slider
        function slinding_q4(app, event)
            app.q(4) = event.Value*app.deg;
            app.robot.model.animate(app.q);
        end

        % Value changed function: xEditField
        function updateX(app, event)
            editRobotPos(app);
        end

        % Value changed function: yEditField
        function updateY(app, event)
            editRobotPos(app);
        end

        % Value changed function: zEditField
        function updateZ(app, event)
            editRobotPos(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'gui_test';
            app.figure1.Tag = 'figure1';
            app.plot = uiaxes(app.figure1);
            app.plot.Tag = 'axes1';
            app.eStopButton = uibutton(app.figure1, 'push');
            app.eStopButton.ButtonPushedFcn = createCallbackFcn(app, @eStop, true);
            app.eStopButton.Text = 'eStop';
            app.ResumeButton = uibutton(app.figure1, 'push');
            app.ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @resume, true);
            app.ResumeButton.Text = 'Resume';
            app.q1SliderLabel = uilabel(app.figure1);
            app.q1SliderLabel.Text = 'q1';
            app.q1Slider = uislider(app.figure1);
            app.q1Slider.Limits = [-135 135];
            app.q1Slider.ValueChangingFcn = createCallbackFcn(app, @sliding_q1, true);
            app.q2SliderLabel = uilabel(app.figure1);
            app.q2SliderLabel.Text = 'q2';
            app.q2Slider = uislider(app.figure1);
            app.q2Slider.Limits = [5 80];
            app.q2Slider.ValueChangingFcn = createCallbackFcn(app, @sliding_q2, true);
            app.q2Slider.Value = 5;
            app.q3SliderLabel = uilabel(app.figure1);
            app.q3SliderLabel.Text = 'q3';
            app.q3Slider = uislider(app.figure1);
            app.q3Slider.Limits = [-90 170];
            app.q3Slider.ValueChangingFcn = createCallbackFcn(app, @sliding_q3, true);
            app.q4SliderLabel = uilabel(app.figure1);
            app.q4SliderLabel.Text = 'q4';
            app.q4Slider = uislider(app.figure1);
            app.q4Slider.Limits = [-90 90];
            app.q4Slider.ValueChangingFcn = createCallbackFcn(app, @slinding_q4, true);
            app.xEditFieldLabel = uilabel(app.figure1);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.figure1, 'numeric');
            app.xEditField.ValueChangedFcn = createCallbackFcn(app, @updateX, true);
            app.xEditField.Value = 0.2604;
            app.yEditFieldLabel = uilabel(app.figure1);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.figure1, 'numeric');
            app.yEditField.ValueChangedFcn = createCallbackFcn(app, @updateY, true);
            app.zEditFieldLabel = uilabel(app.figure1);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.figure1, 'numeric');
            app.zEditField.ValueChangedFcn = createCallbackFcn(app, @updateZ, true);
            app.zEditField.Value = 0.1235;
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)init(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end